classdef Covid_data_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure  matlab.ui.Figure
        AboutMenu                  matlab.ui.container.Menu
        GetDataMenu                matlab.ui.container.Menu
        OptionforcasesButtonGroup  matlab.ui.container.ButtonGroup
        LinechartButton_2          matlab.ui.control.RadioButton
        BarchartButton             matlab.ui.control.RadioButton
        DefaultSettingButton       matlab.ui.control.Button
        EditField_2                matlab.ui.control.EditField
        EditField                  matlab.ui.control.EditField
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        DailyButton                matlab.ui.control.RadioButton
        CumulativeButton           matlab.ui.control.RadioButton
        DatatoplotButtonGroup      matlab.ui.container.ButtonGroup
        BothButton                 matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        CasesButton                matlab.ui.control.RadioButton
        AverageofdaysSlider        matlab.ui.control.Slider
        AverageofdaysSliderLabel   matlab.ui.control.Label
        StateListBox               matlab.ui.control.ListBox
        StateListBoxLabel          matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        CountryLabel               matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        XData
        Cases_Deaths_Matrix
        CasesVec
        DeathsVec
        AverageCases
        AverageDeaths
        AveragingWindow
        Country_title
        State_title
        tab
        Date
        File_title
    end

    methods (Access = private)
    
        function PlotData(app)
            app.UIAxes.reset;
            ta = 'Cumulative Number of Cases in ';
            tb = 'Cumulative Number of Deaths in ';
            tc = 'Cumulative Number of Cases and Deaths in ';
            td = 'Daily Number of Cases in ';
            te = 'Daily Number of Deaths in ';
            tf = 'Daily Number of Cases and Deaths in ';
            half_window = app.AveragingWindow/2;
            start = floor(half_window) + 1;
            stop = length(app.XData) - (ceil(half_window)-1);
            if app.CasesButton.Value && app.CumulativeButton.Value && app.BarchartButton.Value
                app.CumulativeVec;
                app.ComputeAverages;
                bar(app.UIAxes,app.XData(start:stop),app.AverageCases(start:stop),'b');
                num_title = [ta app.Country_title app.State_title app.tab];
                title(app.UIAxes,num_title,'HorizontalAlignment','center');
                ylabel(app.UIAxes,'Cumulative Cases');
            elseif app.CasesButton.Value && app.CumulativeButton.Value && app.LinechartButton_2.Value
                app.CumulativeVec;
                app.ComputeAverages;
                plot(app.UIAxes,app.XData(start:stop),app.AverageCases(start:stop),'b');
                num_title = [ta app.Country_title app.State_title app.tab];
                title(app.UIAxes,num_title,'HorizontalAlignment','center');
                ylabel(app.UIAxes,'Cumulative Cases');
            elseif app.CasesButton.Value && app.DailyButton.Value && app.BarchartButton.Value
                app.DailyVec;
                app.ComputeAverages;
                bar(app.UIAxes,app.XData(start:stop),app.AverageCases(start:stop),'b');
                num_title = [td app.Country_title app.State_title app.tab];
                title(app.UIAxes,num_title,'HorizontalAlignment','center');
                ylabel(app.UIAxes,'Daily Cases');
            elseif app.CasesButton.Value && app.DailyButton.Value && app.LinechartButton_2.Value
                app.DailyVec;
                app.ComputeAverages;
                plot(app.UIAxes,app.XData(start:stop),app.AverageCases(start:stop),'b');
                num_title = [td app.Country_title app.State_title app.tab];
                title(app.UIAxes,num_title,'HorizontalAlignment','center');
                ylabel(app.UIAxes,'Cumulative Cases');
            elseif app.DeathsButton.Value && app.CumulativeButton.Value
                app.CumulativeVec;
                app.ComputeAverages;
                plot(app.UIAxes,app.XData(start:stop),app.AverageDeaths(start:stop),'r');
                num_title = [tb app.Country_title app.State_title app.tab];
                title(app.UIAxes,num_title,'HorizontalAlignment','center');
                ylabel(app.UIAxes,'Cumulative Deaths');
            elseif app.DeathsButton.Value && app.DailyButton.Value
                app.DailyVec;
                app.ComputeAverages;
                plot(app.UIAxes,app.XData(start:stop),app.AverageDeaths(start:stop),'r');
                num_title = [te app.Country_title app.State_title app.tab];
                title(app.UIAxes,num_title,'HorizontalAlignment','center');
                ylabel(app.UIAxes,'Daily Deaths');
            elseif app.BothButton.Value && app.CumulativeButton.Value && app.BarchartButton.Value
                app.CumulativeVec;
                app.ComputeAverages;
                yyaxis(app.UIAxes,'left');
                bar(app.UIAxes,app.XData(start:stop),app.AverageCases(start:stop),'b');
                ylabel(app.UIAxes,'Cumulative Cases');
                set(app.UIAxes,'YTickLabel', num2str(get(app.UIAxes,'YTick').' , '%.f') );
                app.UIAxes.YColor = [0.00 0.00 1.00];
                hold(app.UIAxes,'on');
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,app.XData(start:stop),app.AverageDeaths(start:stop),'r');
                ylabel(app.UIAxes,'Cumulative Deaths');
                app.UIAxes.YColor = [1.00 0.00 0.00];
                num_title = [tc app.Country_title app.State_title app.tab];
                title(app.UIAxes,num_title,'HorizontalAlignment','center');
            elseif app.BothButton.Value && app.CumulativeButton.Value && app.LinechartButton_2.Value
                app.CumulativeVec;
                app.ComputeAverages;
                yyaxis(app.UIAxes,'left');
                plot(app.UIAxes,app.XData(start:stop),app.AverageCases(start:stop),'b');
                ylabel(app.UIAxes,'Cumulative Cases');
                set(app.UIAxes,'YTickLabel', num2str(get(app.UIAxes,'YTick').' , '%.f') );
                app.UIAxes.YColor = [0.00 0.00 1.00];
                hold(app.UIAxes,'on');
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,app.XData(start:stop),app.AverageDeaths(start:stop),'r');
                ylabel(app.UIAxes,'Cumulative Deaths');
                app.UIAxes.YColor = [1.00 0.00 0.00];
                num_title = [tc app.Country_title app.State_title app.tab];
                title(app.UIAxes,num_title,'HorizontalAlignment','center');
            elseif app.BothButton.Value && app.DailyButton.Value && app.BarchartButton.Value
                app.DailyVec;
                app.ComputeAverages;
                yyaxis(app.UIAxes,'left');
                bar(app.UIAxes,app.XData(start:stop),app.AverageCases(start:stop),'b');
                ylabel(app.UIAxes,'Daily Cases');
                set(app.UIAxes,'YTickLabel', num2str(get(app.UIAxes,'YTick').' , '%.f') );
                app.UIAxes.YColor = [0.00 0.00 1.00];
                hold(app.UIAxes,'on');
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,app.XData(start:stop),app.AverageDeaths(start:stop),'r');
                ylabel(app.UIAxes,'Daily Deaths');
                app.UIAxes.YColor = [1.00 0.00 0.00];
                num_title = [tf app.Country_title app.State_title app.tab];
                title(app.UIAxes,num_title,'HorizontalAlignment','center');
            elseif app.BothButton.Value && app.DailyButton.Value && app.LinechartButton_2.Value
                app.DailyVec;
                app.ComputeAverages;
                yyaxis(app.UIAxes,'left');
                plot(app.UIAxes,app.XData(start:stop),app.AverageCases(start:stop),'b');
                ylabel(app.UIAxes,'Daily Cases');
                set(app.UIAxes,'YTickLabel', num2str(get(app.UIAxes,'YTick').' , '%.f') );
                app.UIAxes.YColor = [0.00 0.00 1.00];
                hold(app.UIAxes,'on');
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,app.XData(start:stop),app.AverageDeaths(start:stop),'r');
                ylabel(app.UIAxes,'Daily Deaths');
                app.UIAxes.YColor = [1.00 0.00 0.00];
                num_title = [tf app.Country_title app.State_title app.tab];
                title(app.UIAxes,num_title,'HorizontalAlignment','center');
            end
            xlim(app.UIAxes,[app.XData(1)-21,app.XData(end)+2]); xlim(app.UIAxes,'manual');
            app.UIAxes.YLimMode = 'auto'; app.UIAxes.YTickMode = 'auto';
            xlabel(app.UIAxes,'Date(mm/dd)'); grid(app.UIAxes,'on');
            datetick(app.UIAxes,'x','mm/dd','keeplimits');
            set(app.UIAxes,'YTickLabel', num2str(get(app.UIAxes,'YTick').' , '%.f') );
            hold(app.UIAxes,'off'); app.File_title = [app.Country_title app.State_title];
        end
        function ComputeAverages(app)
            app.AverageCases = movmean(app.CasesVec,app.AveragingWindow);
            app.AverageDeaths = movmean(app.DeathsVec,app.AveragingWindow);
        end
        function DailyVec(app)
            app.CumulativeVec;
            x = app.CasesVec - circshift(app.CasesVec,1);
            x(1) = app.CasesVec(1);
            x(x<0) = 0;
            app.CasesVec = x;
            y = app.DeathsVec - circshift(app.DeathsVec,1);
            y(1) = app.DeathsVec(1);
            y(y<0) = 0;
            app.DeathsVec = y;
        end
        function CumulativeVec(app)
            Selected_Item = Data(app.CountryListBox.Value);
            [~,in1] = ismember(app.CountryListBox.Value,app.CountryListBox.Items);
            [~,in2] = ismember(app.StateListBox.Value,app.StateListBox.Items);
            if in1 == 1
                app.Cases_Deaths_Matrix = Selected_Item.global_cases_and_deaths;
                app.CasesVec = app.Cases_Deaths_Matrix(1,:);
                app.DeathsVec = app.Cases_Deaths_Matrix(2,:);
            else
                app.CasesVec = Selected_Item.Cases_Vector(Selected_Item.CountryRows(in2));
                app.DeathsVec = Selected_Item.Deaths_Vector(Selected_Item.CountryRows(in2));
            end
        end
        function setDefaults(app)
            app.UIAxes.reset;
            globalData = Data('global');
            app.CountryListBox.Items = unique(globalData.CountryName,'stable');
            app.StateListBox.Items = unique(globalData.StateName,'stable');
            app.Cases_Deaths_Matrix = globalData.global_cases_and_deaths();
            app.Date = globalData.Date;
            app.CasesVec = app.Cases_Deaths_Matrix(1,:);
            app.DeathsVec = app.Cases_Deaths_Matrix(2,:);
            startDate = datenum(globalData.Date(1));
            endDate = datenum(globalData.Date(end));
            app.XData = linspace(startDate,endDate,length(globalData.Date));
            app.AveragingWindow = 1;
            app.AverageofdaysSlider.Value = 1;
            
            % set widgets
            app.CountryListBox.Value = 'Global';
            app.StateListBox.Value = 'All';
            app.CasesButton.Value = true;
            app.DeathsButton.Value = false;
            app.BothButton.Value = false;
            app.CumulativeButton.Value = true;
            app.DailyButton.Value = false;
            app.BarchartButton.Value = true;
            app.LinechartButton_2.Value = false;
            
            app.StateListBoxValueChanged;
        end
        function shortName(app,name)
            if ~ischar(name)
                error('Input must be character array');
            end
            first = string(name(1));
            k = find(name == ' ');
            other = "";
            for ii = 1:length(k)
                other = other + string(name(k(ii)+1));
            end
            app.State_title = char(", "+first + other);
        end        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setDefaults;
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            Selected_Item = Data(app.CountryListBox.Value);
            app.StateListBox.Items = unique(Selected_Item.StateName,'stable');
            app.StateListBoxValueChanged;
        end

        % Value changed function: StateListBox
        function StateListBoxValueChanged(app, event)
            [~,in2] = ismember(app.StateListBox.Value,app.StateListBox.Items);
            app.Country_title = app.CountryListBox.Value;
            if in2 ~= 1
                if ismember(' ',app.StateListBox.Value)
                    app.shortName(app.StateListBox.Value);
                else
                    app.State_title = char(", " + string(app.StateListBox.Value));
                end
            else
                app.State_title = '';
            end
            app.PlotData;            
        end

        % Value changing function: AverageofdaysSlider
        function AverageofdaysSliderValueChanging(app, event)
            app.AveragingWindow = round(abs(event.Value));
            if app.AveragingWindow > 1
                app.tab = [' (' char(string(round(app.AveragingWindow))) '-day mean)'];
            else
                app.tab = '';
            end
            app.PlotData
        end

        % Selection changed function: DatatoplotButtonGroup
        function DatatoplotButtonGroupSelectionChanged(app, event)
            app.PlotData;
            
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.PlotData;
            
        end

        % Selection changed function: OptionforcasesButtonGroup
        function OptionforcasesButtonGroupSelectionChanged(app, event)
            app.PlotData;
            
        end

        % Button pushed function: DefaultSettingButton
        function DefaultSettingButtonPushed(app, event)
            app.setDefaults;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            if ismember(event.Value,app.CountryListBox.Items)
                app.CountryListBox.Value = event.Value;
                app.CountryListBoxValueChanged;
            else
                msgbox('Invalid Country Name!', 'Error','error');
            end
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            if ismember(event.Value,app.StateListBox.Items)
                app.StateListBox.Value = event.Value;
                app.StateListBoxValueChanged;
            else
                msgbox('Invalid State Name!', 'Error','error');
            end
            
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            about = fileread('about.txt');
            msgbox(about,"About Info");
        end

        % Menu selected function: GetDataMenu
        function GetDataMenuSelected(app, event)
            handle = fopen('Covid-19 Data.txt','w');
            if app.DailyButton.Value
                fprintf(handle,'%s%s%s\n\n','Daily ','Cases and Deaths in ',app.File_title);
            else
                fprintf(handle,'%s%s%s\n\n','Cumulative ','Cases and Deaths in ',app.File_title);
            end
            fprintf(handle,'%s     %s     %s\n','Date','Cases','Deaths');
               for i = 1:length(app.Date)
                   fprintf(handle,'%s   ',app.Date{i}(1,:));
                   fprintf(handle,'%.2f     ',app.AverageCases(i));
                   fprintf(handle,'%.2f\n',app.AverageDeaths(i));
               end
            fclose(handle);
            open('Covid-19 Data.txt');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure = uifigure('Visible', 'off');
            app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure.Name = 'COVID-19 Data Visualization (Data source: Johns Hopkins University)';
            app.AboutMenu = uimenu(app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GetDataMenu = uimenu(app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure);
            app.GetDataMenu.MenuSelectedFcn = createCallbackFcn(app, @GetDataMenuSelected, true);
            app.GetDataMenu.Text = 'Get Data';
            app.UIAxes = uiaxes(app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Date(mm/dd)')
            ylabel(app.UIAxes, 'Y')
            app.CountryLabel = uilabel(app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure);
            app.CountryLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateListBoxLabel = uilabel(app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure);
            app.StateListBoxLabel.Text = 'State:';
            app.StateListBox = uilistbox(app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure);
            app.StateListBox.ValueChangedFcn = createCallbackFcn(app, @StateListBoxValueChanged, true);
            app.AverageofdaysSliderLabel = uilabel(app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure);
            app.AverageofdaysSliderLabel.Text = 'Average of days';
            app.AverageofdaysSlider = uislider(app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure);
            app.AverageofdaysSlider.Limits = [1 15];
            app.AverageofdaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AverageofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AverageofdaysSliderValueChanging, true);
            app.AverageofdaysSlider.Value = 1;
            app.DatatoplotButtonGroup = uibuttongroup(app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure);
            app.DatatoplotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoplotButtonGroupSelectionChanged, true);
            app.DatatoplotButtonGroup.Title = 'Data to plot';
            app.CasesButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.CasesButton.Text = ' Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.EditField = uieditfield(app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.DefaultSettingButton = uibutton(app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure, 'push');
            app.DefaultSettingButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultSettingButtonPushed, true);
            app.DefaultSettingButton.Text = 'Default Setting';
            app.OptionforcasesButtonGroup = uibuttongroup(app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure);
            app.OptionforcasesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionforcasesButtonGroupSelectionChanged, true);
            app.OptionforcasesButtonGroup.Title = 'Option for cases';
            app.BarchartButton = uiradiobutton(app.OptionforcasesButtonGroup);
            app.BarchartButton.Text = 'Bar chart';
            app.BarchartButton.Value = true;
            app.LinechartButton_2 = uiradiobutton(app.OptionforcasesButtonGroup);
            app.LinechartButton_2.Text = 'Line chart';
            % Show the figure after all components are created
            app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Covid_data_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19DataVisualizationDatasourceJohnHopkinsUniversityUIFigure)
        end
    end
end